classdef video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        BackButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AudioPlayer audioplayer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


            global cont;
            cont = 1;

            f = parfeval(backgroundPool,@value_teste,1);
            vid = VideoReader('video_cortado.mp4');
     
            axes_handle = app.UIAxes;

            audio_player = audioplayer(audioread('teste.mp3'), 44100);

            while hasFrame(vid)
                img = readFrame(vid);
                imshow(img, 'Parent', axes_handle);
                drawnow;

                play(audio_player);
            end
            
            cont = false;

            val = fetchOutputs(f);
            assignin('base', 'val', val);

            stop(audio_player);
            delete(audio_player)

            scoreWindow = score;
            scoreFig = scoreWindow.UIFigure; 

            app.UIFigure.UserData.scoreFig = scoreFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end